classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        StartButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        InputTabGroup                  matlab.ui.container.TabGroup
        ModelTab                       matlab.ui.container.Tab
        DetachmentRateEditField        matlab.ui.control.NumericEditField
        DetachmentrateEditFieldLabel   matlab.ui.control.Label
        OsmosisRateEditField           matlab.ui.control.NumericEditField
        OsmosisrateEditFieldLabel      matlab.ui.control.Label
        BiofilmPorosityEditField       matlab.ui.control.NumericEditField
        BiofilmporosityEditFieldLabel  matlab.ui.control.Label
        WaterDensityEditField          matlab.ui.control.NumericEditField
        WaterdensityEditFieldLabel     matlab.ui.control.Label
        LoadButton                     matlab.ui.control.Button
        OptionsTab                     matlab.ui.container.Tab
        VerboseCheckBox                matlab.ui.control.CheckBox
        UITable                        matlab.ui.control.Table
        FinalTimeEditField             matlab.ui.control.NumericEditField
        FinaltimeEditFieldLabel        matlab.ui.control.Label
        NumFramesEditField             matlab.ui.control.NumericEditField
        NframesEditFieldLabel          matlab.ui.control.Label
        TimeStepEditField              matlab.ui.control.NumericEditField
        TimestepEditFieldLabel         matlab.ui.control.Label
        ViewTab                        matlab.ui.container.Tab
        FixedXaxisCheckBox             matlab.ui.control.CheckBox
        TimeSlider                     matlab.ui.control.Slider
        TimeSliderLabel                matlab.ui.control.Label
        VolumeDropDown                 matlab.ui.control.DropDown
        VolumeDropDownLabel            matlab.ui.control.Label
        ComponentDropDown              matlab.ui.control.DropDown
        ComponentDropDownLabel         matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        PlotTabGroup                   matlab.ui.container.TabGroup
        BiofilmvolfracTab              matlab.ui.container.Tab
        TimeLabel                      matlab.ui.control.Label
        UIAxesPlot1D                   matlab.ui.control.UIAxes
        ConcentrationTab               matlab.ui.container.Tab
        UIAxesPlot2D                   matlab.ui.control.UIAxes
        PlotModelTab                   matlab.ui.container.Tab
        UIAxesModel                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Filter % SandFilter = SandFilter()
        MPCModel % Model = Model()
        %FilterState State = State()
        MPCResults % Results = Results()
        Options % struct
    end
    
    methods (Access = private)
        
        function updateTimeLabel(app, time)
            app.TimeLabel.Text = sprintf("$T = %4.4g$", time);
        end
        
        function plot1D(app)
            arguments
                app
            end
            time = app.TimeSlider.Value / app.TimeSlider.Limits(2) * app.MPCResults.TimeFinal;
            app.MPCResults.plotBiofilm(time, AxisHandle = app.UIAxesPlot1D);
            if app.FixedXaxisCheckBox.Value
                xlim(app.UIAxesPlot1D, [0, 1]);
            else
                xlim(app.UIAxesPlot1D, "auto");
            end
            drawnow;
            updateTimeLabel(app, app.MPCResults.TimeFinal);
        end
        
        function plot2D(app)
            arguments
                app
            end
            component_name = app.ComponentDropDown.Value;
            volume_type = app.VolumeDropDown.Value;
            app.MPCResults.plotConcentration(component_name, volume_type, ...
                AxesHandle=app.UIAxesPlot2D)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initpath()
            
            app.Filter = SandFilter();
            app.MPCModel = Model();
            app.MPCModel.DetachmentFunction = @(v) app.DetachmentRateEditField.Value * sqrt(abs(v));

            app.MPCResults = Results();
            
            app.Filter = app.Filter.addGridPoints(200);
            app.Options = struct(...
                "CloggingFraction", 1.0);

            app.UIAxesPlot1D.XLabel.Interpreter = 'latex';
            app.UIAxesPlot1D.XLabel.String = "$\phi_{\mathrm{b}}$";
            app.UIAxesPlot1D.XLabel.FontSize = 22;
            app.UIAxesPlot1D.YLabel.Interpreter = 'latex';
            app.UIAxesPlot1D.YLabel.FontSize = 22;

            NumFramesEditFieldValueChanged(app);
        end

        % Button pushed function: LoadButton
        function LoadModel(app, event)
            [filename, filedir] = uigetfile(".mat");
            if ~isempty(filename)
                app.MPCModel = load([filedir, filename]).model;
                app.WaterDensityEditField.Value = app.MPCModel.WaterDensity;
                app.BiofilmPorosityEditField.Value = app.MPCModel.BiofilmPorosity;
                app.OsmosisRateEditField.Value = app.MPCModel.OsmosisRate;
    
                app.MPCModel.DetachmentFunction = @(v) app.DetachmentRateEditField.Value * sqrt(abs(v));
                
                component_names = string([app.MPCModel.Components.Name]);
                app.ComponentDropDown.Items = component_names;
                app.UITable.RowName = component_names;
                app.UITable.Data = table(zeros(length(component_names), 1));
    
                app.MPCModel.plot(app.UIAxesModel);

            end
        end

        % Value changed function: WaterDensityEditField
        function WaterDensityChanged(app, event)
            value = app.WaterDensityEditField.Value;
            app.MPCModel.WaterDensity = value;
        end

        % Value changed function: BiofilmPorosityEditField
        function BiofilmPorosityChanged(app, event)
            value = app.BiofilmPorosityEditField.Value;
            app.MPCModel.BiofilmPorosity = value;
        end

        % Value changed function: OsmosisRateEditField
        function OsmosisRateChanged(app, event)
            value = app.OsmosisRateEditField.Value;
            app.MPCModel.OsmosisRate = value;
        end

        % Value changed function: DetachmentRateEditField
        function DetachmentRateChanged(app, event)
            value = app.DetachmentRateEditField.Value;
            app.MPCModel.DetachmentFunction = @(v) value * sqrt(abs(v));
        end

        % Button pushed function: StartButton
        function StartSimulation(app, event)
            state = State(app.Filter, app.MPCModel);
            disp("Starting simulation ...")
            inflow_keys = string(app.UITable.RowName);
            inflow_values = app.UITable.Data{:, 1};
            if app.VerboseCheckBox.Value
                app.MPCResults = simulate(state, ...
                    InflowConcentrations=dictionary(inflow_keys, inflow_values), ...
                    SimulationTime=app.FinalTimeEditField.Value);
            else
                app.MPCResults = simulate(state, ...
                    InflowConcentrations=dictionary(inflow_keys, inflow_values), ...
                    SimulationTime=app.FinalTimeEditField.Value, ...
                    Quiet=true);
            end
            disp("Simulation finished.")
            app.TimeSlider.Value = app.MPCResults.TimeFinal;
            plot1D(app);
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = event.Value;
            time = changingValue * app.FinalTimeEditField.Value / app.NumFramesEditField.Value;
            updateTimeLabel(app, time);
        end

        % Value changed function: FinalTimeEditField, NumFramesEditField
        function NumFramesEditFieldValueChanged(app, event)
            framesValue = app.NumFramesEditField.Value;
            app.TimeSlider.Limits = [0 framesValue];
            app.TimeSlider.MajorTicks = round(linspace(0, framesValue, 6));

            time = app.FinalTimeEditField.Value;
            app.TimeSlider.MajorTickLabels = string(linspace(0, time, 6));
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            plot1D(app);
        end

        % Value changed function: ComponentDropDown, VolumeDropDown
        function ComponentDropDownValueChanged(app, event)
            plot2D(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MPC-SSF GUI';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.Tag = 'gui';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputTabGroup = uitabgroup(app.LeftPanel);
            app.ModelTab = uitab(app.InputTabGroup);
            app.ModelTab.Title = 'Model';
            app.LoadButton = uibutton(app.ModelTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModel, true);
            app.LoadButton.Text = 'LOAD';
            app.WaterdensityEditFieldLabel = uilabel(app.ModelTab);
            app.WaterdensityEditFieldLabel.Text = 'Water density';
            app.WaterDensityEditField = uieditfield(app.ModelTab, 'numeric');
            app.WaterDensityEditField.ValueChangedFcn = createCallbackFcn(app, @WaterDensityChanged, true);
            app.BiofilmporosityEditFieldLabel = uilabel(app.ModelTab);
            app.BiofilmporosityEditFieldLabel.Text = 'Biofilm porosity';
            app.BiofilmPorosityEditField = uieditfield(app.ModelTab, 'numeric');
            app.BiofilmPorosityEditField.ValueChangedFcn = createCallbackFcn(app, @BiofilmPorosityChanged, true);
            app.OsmosisrateEditFieldLabel = uilabel(app.ModelTab);
            app.OsmosisrateEditFieldLabel.Text = 'Osmosis rate';
            app.OsmosisRateEditField = uieditfield(app.ModelTab, 'numeric');
            app.OsmosisRateEditField.ValueChangedFcn = createCallbackFcn(app, @OsmosisRateChanged, true);
            app.DetachmentrateEditFieldLabel = uilabel(app.ModelTab);
            app.DetachmentrateEditFieldLabel.Text = 'Detachment rate';
            app.DetachmentRateEditField = uieditfield(app.ModelTab, 'numeric');
            app.DetachmentRateEditField.ValueChangedFcn = createCallbackFcn(app, @DetachmentRateChanged, true);
            app.OptionsTab = uitab(app.InputTabGroup);
            app.OptionsTab.Title = 'Options';
            app.TimestepEditFieldLabel = uilabel(app.OptionsTab);
            app.TimestepEditFieldLabel.Text = 'Time step';
            app.TimeStepEditField = uieditfield(app.OptionsTab, 'numeric');
            app.TimeStepEditField.ValueDisplayFormat = '%11.4e';
            app.TimeStepEditField.Value = 1e-06;
            app.NframesEditFieldLabel = uilabel(app.OptionsTab);
            app.NframesEditFieldLabel.Text = 'N. frames';
            app.NumFramesEditField = uieditfield(app.OptionsTab, 'numeric');
            app.NumFramesEditField.RoundFractionalValues = 'on';
            app.NumFramesEditField.ValueDisplayFormat = '%11.3i';
            app.NumFramesEditField.ValueChangedFcn = createCallbackFcn(app, @NumFramesEditFieldValueChanged, true);
            app.NumFramesEditField.Value = 200;
            app.FinaltimeEditFieldLabel = uilabel(app.OptionsTab);
            app.FinaltimeEditFieldLabel.Text = 'Final time';
            app.FinalTimeEditField = uieditfield(app.OptionsTab, 'numeric');
            app.FinalTimeEditField.ValueChangedFcn = createCallbackFcn(app, @NumFramesEditFieldValueChanged, true);
            app.FinalTimeEditField.Value = 1;
            app.UITable = uitable(app.OptionsTab);
            app.UITable.ColumnName = {'Inflow [g/L]'};
            app.UITable.RowName = {'A'; 'B'};
            app.UITable.ColumnEditable = true;
            app.VerboseCheckBox = uicheckbox(app.OptionsTab);
            app.VerboseCheckBox.Text = 'Verbose';
            app.VerboseCheckBox.Value = true;
            app.ViewTab = uitab(app.InputTabGroup);
            app.ViewTab.Title = 'View';
            app.ComponentDropDownLabel = uilabel(app.ViewTab);
            app.ComponentDropDownLabel.Text = 'Component';
            app.ComponentDropDown = uidropdown(app.ViewTab);
            app.ComponentDropDown.Items = {};
            app.ComponentDropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentDropDownValueChanged, true);
            app.ComponentDropDown.Value = {};
            app.VolumeDropDownLabel = uilabel(app.ViewTab);
            app.VolumeDropDownLabel.Text = 'Volume';
            app.VolumeDropDown = uidropdown(app.ViewTab);
            app.VolumeDropDown.Items = {'Matrix', 'Enclosed', 'Flowing'};
            app.VolumeDropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentDropDownValueChanged, true);
            app.VolumeDropDown.Value = 'Matrix';
            app.TimeSliderLabel = uilabel(app.ViewTab);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.ViewTab);
            app.TimeSlider.Limits = [0 200];
            app.TimeSlider.MajorTickLabels = {'0', '40', '80', '120', '160', '200'};
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.TimeSlider.Step = 1;
            app.FixedXaxisCheckBox = uicheckbox(app.ViewTab);
            app.FixedXaxisCheckBox.Text = 'Fixed X-axis';
            app.FixedXaxisCheckBox.Value = true;
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.Text = 'STOP';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulation, true);
            app.StartButton.Text = 'START';
            app.RightPanel = uipanel(app.GridLayout);
            app.PlotTabGroup = uitabgroup(app.RightPanel);
            app.BiofilmvolfracTab = uitab(app.PlotTabGroup);
            app.BiofilmvolfracTab.Title = 'Biofilm vol. frac.';
            app.UIAxesPlot1D = uiaxes(app.BiofilmvolfracTab);
            ylabel(app.UIAxesPlot1D, 'Depth [m]')
            app.UIAxesPlot1D.YLimitMethod = 'tight';
            app.UIAxesPlot1D.YDir = 'reverse';
            app.TimeLabel = uilabel(app.BiofilmvolfracTab);
            app.TimeLabel.Interpreter = 'latex';
            app.TimeLabel.Text = '$T = 0.0$';
            app.ConcentrationTab = uitab(app.PlotTabGroup);
            app.ConcentrationTab.Title = 'Concentration';
            app.UIAxesPlot2D = uiaxes(app.ConcentrationTab);
            xlabel(app.UIAxesPlot2D, 'Time')
            ylabel(app.UIAxesPlot2D, 'Depth')
            app.PlotModelTab = uitab(app.PlotTabGroup);
            app.PlotModelTab.Title = 'Model';
            app.UIAxesModel = uiaxes(app.PlotModelTab);
            app.UIAxesModel.XTick = [];
            app.UIAxesModel.YTick = [];
            app.UIAxesModel.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end